classdef Sustainability_Performance_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Title               matlab.ui.control.Label
        MakeyourSettings    matlab.ui.control.Label
        TargetSectorsLabel  matlab.ui.control.Label
        Target_Sector       matlab.ui.control.ListBox
        TargetRegionsLabel  matlab.ui.control.Label
        Target_Region       matlab.ui.control.ListBox
        YearsLabel          matlab.ui.control.Label
        Years               matlab.ui.control.ListBox
        HowdoyouwanttodisplaytheresultsLabel  matlab.ui.control.Label
        Output              matlab.ui.control.ListBox
        InsertthenameofyourfolderwheretheresultswillbestoredLabel  matlab.ui.control.Label
        FolderName          matlab.ui.control.EditField
        StartCalculation    matlab.ui.control.Button
        ProgressLabel       matlab.ui.control.Label
        Progress            matlab.ui.control.EditField
        ForquestionscontactliviacabernardistpethzchLabel  matlab.ui.control.Label
        Panel               matlab.ui.container.Panel
        Label               matlab.ui.control.Label
        EnvironmentalEandSocioEconomicIndicatorsSLabel  matlab.ui.control.Label
        Indicators          matlab.ui.control.ListBox
        Panel_2             matlab.ui.container.Panel
        WhichresultsdoyouwanttodisplayLabel  matlab.ui.control.Label
        Results             matlab.ui.control.ListBox
        Label_2             matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: StartCalculation
        function StartCalculationButtonPushed(app, event)
            app.Progress.Value = 'Calculation is in progress';
            index_t_s = str2double(app.Target_Sector.Value);
            index_t_r = str2double(app.Target_Region.Value);
            index_years = str2double(app.Years.Value);
            index_indicators = str2double(app.Indicators.Value);
            index_results = str2double(app.Results.Value);
            index_output = str2double(app.Output.Value);
            folder_name = app.FolderName.Value;
            index_t_s = sort(index_t_s,'ascend');
            index_t_r = sort(index_t_r,'ascend');
            index_years = sort(index_years,'ascend');
            index_indicators = sort(index_indicators,'ascend');
            index_results = sort(index_results,'ascend');
            index_output = sort(index_output,'ascend');
            % addpath('Functions_Tool/');
            Main_Function_Tool(index_t_s, index_t_r, index_indicators, index_years, index_results, index_output, folder_name);
            app.Progress.Value = 'Calculation finished: Results are stored in your folder';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Matlab Tool To Analyze Sustainability Performance of Target-Sector-Regions Upstream and Downstream the Global Value Chain';
            app.MakeyourSettings = uilabel(app.UIFigure);
            app.MakeyourSettings.Text = '1) Make your Settings (press command to select several items)';
            app.TargetSectorsLabel = uilabel(app.UIFigure);
            app.TargetSectorsLabel.Text = 'Target-Sectors';
            app.Target_Sector = uilistbox(app.UIFigure);
            app.Target_Sector.Items = {'Biomass extraction: 	Cultivation of paddy rice', 'Biomass extraction: 	Cultivation of wheat', 'Biomass extraction: 	Cultivation of cereal grains nec', 'Biomass extraction: 	Cultivation of vegetables & fruit and nuts', 'Biomass extraction: 	Cultivation of oil seeds', 'Biomass extraction: 	Cultivation of sugar cane and sugar beet', 'Biomass extraction: 	Cultivation of plant-based fibers', 'Biomass extraction: 	Cultivation of crops nec', 'Biomass extraction: 	Cattle farming', 'Biomass extraction: 	Pigs farming', 'Biomass extraction: 	Poultry farming', 'Biomass extraction: 	Meat animals nec', 'Biomass extraction: 	Animal products nec', 'Biomass extraction: 	Raw milk', 'Biomass extraction: 	Wool and silk-worm cocoons', 'Biomass extraction: 	Manure treatment (conventional) storage and land application', 'Biomass extraction: 	Manure treatment (biogas) storage and land application', 'Biomass extraction: 	Forestry logging and related service activities', 'Biomass extraction: 	Fishing operating of fish hatcheries and fish farms and service activities incidental to fishing', 'Biomass processing: 	Processing of meat cattle', 'Biomass processing: 	Processing of meat pigs', 'Biomass processing: 	Processing of meat poultry', 'Biomass processing: 	Production of meat products nec', 'Biomass processing: 	Processing vegetable oils and fats', 'Biomass processing: 	Processing of dairy products', 'Biomass processing: 	Processed rice', 'Biomass processing: 	Sugar refining', 'Biomass processing: 	Processing of Food products nec', 'Biomass processing: 	Re-processing of secondary wood material into new wood material', 'Biomass processing: 	Pulp', 'Biomass processing: 	Re-processing of secondary paper into new pulp', 'Fossils extraction: 	Mining of coal and lignite and extraction of peat', 'Fossils extraction: 	Extraction of crude petroleum and services related to crude oil extraction (excluding surveying)', 'Fossils extraction: 	Extraction of natural gas and services related to natural gas extraction (excluding surveying)', 'Fossils extraction: 	Extraction liquefaction and regasification of other petroleum and gaseous materials', 'Fossils processing: 	Manufacture of coke oven products', 'Fossils processing: 	Petroleum Refinery', 'Fossils processing: 	Plastics basic', 'Fossils processing: 	Re-processing of secondary plastic into new plastic', 'Fossils processing: 	Chemicals nec', 'Fossils processing: 	Transport via pipelines', 'Metals extraction: 	Mining of uranium and thorium ores', 'Metals extraction: 	Mining of iron ores', 'Metals extraction: 	Mining of copper ores and concentrates', 'Metals extraction: 	Mining of nickel ores and concentrates', 'Metals extraction: 	Mining of aluminium ores and concentrates', 'Metals extraction: 	Mining of precious metal ores and concentrates', 'Metals extraction: 	Mining of lead & zinc and tin ores and concentrates', 'Metals extraction: 	Mining of other non-ferrous metal ores and concentrates', 'Metals processsing: 	Processing of nuclear fuel', 'Metals processsing: 	Manufacture of basic iron and steel and of ferro-alloys and first products thereof', 'Metals processsing: 	Re-processing of secondary steel into new steel', 'Metals processsing: 	Precious metals production', 'Metals processsing: 	Re-processing of secondary preciuos metals into new preciuos metals', 'Metals processsing: 	Aluminium production', 'Metals processsing: 	Re-processing of secondary aluminium into new aluminium', 'Metals processsing: 	Lead & zinc and tin production', 'Metals processsing: 	Re-processing of secondary lead into new lead', 'Metals processsing: 	Copper production', 'Metals processsing: 	Re-processing of secondary copper into new copper', 'Metals processsing: 	Other non-ferrous metal production', 'Metals processsing: 	Re-processing of secondary other non-ferrous metals into new other non-ferrous metals', 'Metals processsing: 	Casting of metals', 'Non-metallic minerals extraction: 	Quarrying of stone', 'Non-metallic minerals extraction: 	Quarrying of sand and clay', 'Non-metallic minerals extraction: 	Mining of chemical and fertilizer minerals & production of salt & other mining and quarrying n.e.c.', 'Non-metallic minerals processing: 	N-fertiliser', 'Non-metallic minerals processing: 	P- and other fertiliser', 'Non-metallic minerals processing: 	Manufacture of glass and glass products', 'Non-metallic minerals processing: 	Re-processing of secondary glass into new glass', 'Non-metallic minerals processing: 	Manufacture of ceramic goods', 'Non-metallic minerals processing: 	Manufacture of bricks & tiles and construction products in baked clay', 'Non-metallic minerals processing: 	Manufacture of cement & lime and plaster', 'Non-metallic minerals processing: 	Re-processing of ash into clinker', 'Non-metallic minerals processing: 	Manufacture of other non-metallic mineral products n.e.c.', 'Remaining economy: 	Manufacture of beverages', 'Remaining economy: 	Manufacture of fish products', 'Remaining economy: 	Manufacture of tobacco products', 'Remaining economy: 	Manufacture of textiles', 'Remaining economy: 	Manufacture of wearing apparel & dressing and dyeing of fur', 'Remaining economy: 	Tanning and dressing of leather & manufacture of luggage & handbags & saddlery & harness and footwear', 'Remaining economy: 	Manufacture of wood and of products of wood and cork (except furniture and manufacture of articles of straw and plaiting materials)', 'Remaining economy: 	Paper', 'Remaining economy: 	Publishing & printing and reproduction of recorded media', 'Remaining economy: 	Manufacture of rubber and plastic products', 'Remaining economy: 	Manufacture of fabricated metal products (except machinery and equipment)', 'Remaining economy: 	Manufacture of machinery and equipment n.e.c.', 'Remaining economy: 	Manufacture of office machinery and computers', 'Remaining economy: 	Manufacture of electrical machinery and apparatus n.e.c.', 'Remaining economy: 	Manufacture of radio & television and communication equipment and apparatus', 'Remaining economy: 	Manufacture of medical & precision and optical instruments & watches and clocks', 'Remaining economy: 	Manufacture of motor vehicles & trailers and semi-trailers', 'Remaining economy: 	Manufacture of other transport equipment', 'Remaining economy: 	Manufacture of furniture & manufacturing n.e.c.', 'Remaining economy: 	Recycling of waste and scrap', 'Remaining economy: 	Recycling of bottles by direct reuse', 'Remaining economy: 	Production of electricity by coal', 'Remaining economy: 	Production of electricity by gas', 'Remaining economy: 	Production of electricity by nuclear', 'Remaining economy: 	Production of electricity by hydro', 'Remaining economy: 	Production of electricity by wind', 'Remaining economy: 	Production of electricity by petroleum and other oil derivatives', 'Remaining economy: 	Production of electricity by biomass and waste', 'Remaining economy: 	Production of electricity by solar photovoltaic', 'Remaining economy: 	Production of electricity by solar thermal', 'Remaining economy: 	Production of electricity by tide & wave & ocean', 'Remaining economy: 	Production of electricity by Geothermal', 'Remaining economy: 	Production of electricity nec', 'Remaining economy: 	Transmission of electricity', 'Remaining economy: 	Distribution and trade of electricity', 'Remaining economy: 	Manufacture of gas & distribution of gaseous fuels through mains', 'Remaining economy: 	Steam and hot water supply', 'Remaining economy: 	Collection & purification and distribution of water', 'Remaining economy: 	Construction', 'Remaining economy: 	Re-processing of secondary construction material into aggregates', 'Remaining economy: 	Sale & maintenance & repair of motor vehicles & motor vehicles parts & motorcycles & motor cycles parts and accessoiries', 'Remaining economy: 	Retail sale of automotive fuel', 'Remaining economy: 	Wholesale trade and commission trade (except of motor vehicles and motorcycles)', 'Remaining economy: 	Retail trade (except of motor vehicles and motorcycles and repair of personal and household goods)', 'Remaining economy: 	Hotels and restaurants', 'Remaining economy: 	Transport via railways', 'Remaining economy: 	Other land transport', 'Remaining economy: 	Sea and coastal water transport', 'Remaining economy: 	Inland water transport', 'Remaining economy: 	Air transport', 'Remaining economy: 	Supporting and auxiliary transport activities & activities of travel agencies', 'Remaining economy: 	Post and telecommunications', 'Remaining economy: 	Financial intermediation (except insurance and pension funding)', 'Remaining economy: 	Insurance and pension funding (except compulsory social security)', 'Remaining economy: 	Activities auxiliary to financial intermediation', 'Remaining economy: 	Real estate activities', 'Remaining economy: 	Renting of machinery and equipment (without operator and of personal and household goods)', 'Remaining economy: 	Computer and related activities', 'Remaining economy: 	Research and development', 'Remaining economy: 	Other business activities', 'Remaining economy: 	Public administration and defence & compulsory social security', 'Remaining economy: 	Education', 'Remaining economy: 	Health and social work', 'Remaining economy: 	Incineration of waste: Food', 'Remaining economy: 	Incineration of waste: Paper', 'Remaining economy: 	Incineration of waste: Plastic', 'Remaining economy: 	Incineration of waste: Metals and Inert materials', 'Remaining economy: 	Incineration of waste: Textiles', 'Remaining economy: 	Incineration of waste: Wood', 'Remaining economy: 	Incineration of waste: Oil/Hazardous waste', 'Remaining economy: 	Biogasification of food waste incl. land application', 'Remaining economy: 	Biogasification of paper incl. land application', 'Remaining economy: 	Biogasification of sewage slugde incl. land application', 'Remaining economy: 	Composting of food waste incl. land application', 'Remaining economy: 	Composting of paper and wood incl. land application', 'Remaining economy: 	Waste water treatment food', 'Remaining economy: 	Waste water treatment other', 'Remaining economy: 	Landfill of waste: Food', 'Remaining economy: 	Landfill of waste: Paper', 'Remaining economy: 	Landfill of waste: Plastic', 'Remaining economy: 	Landfill of waste: Inert/metal/hazardous', 'Remaining economy: 	Landfill of waste: Textiles', 'Remaining economy: 	Landfill of waste: Wood', 'Remaining economy: 	Activities of membership organisation n.e.c.', 'Remaining economy: 	Recreational & cultural and sporting activities', 'Remaining economy: 	Other service activities', 'Remaining economy: 	Private households with employed persons', 'Remaining economy: 	Extra-territorial organizations and bodies'};
            app.Target_Sector.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81', '82', '83', '84', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94', '95', '96', '97', '98', '99', '100', '101', '102', '103', '104', '105', '106', '107', '108', '109', '110', '111', '112', '113', '114', '115', '116', '117', '118', '119', '120', '121', '122', '123', '124', '125', '126', '127', '128', '129', '130', '131', '132', '133', '134', '135', '136', '137', '138', '139', '140', '141', '142', '143', '144', '145', '146', '147', '148', '149', '150', '151', '152', '153', '154', '155', '156', '157', '158', '159', '160', '161', '162', '163'};
            app.Target_Sector.Multiselect = 'on';
            app.Target_Sector.Value = {'1'};
            app.TargetRegionsLabel = uilabel(app.UIFigure);
            app.TargetRegionsLabel.Text = 'Target-Regions';
            app.Target_Region = uilistbox(app.UIFigure);
            app.Target_Region.Items = {'Africa: 	South Africa', 'Africa: 	RoW Africa', 'Australia: 	Australia', 'China: 	China', 'China: 	Taiwan', 'Europe: 	Austria', 'Europe: 	Belgium', 'Europe: 	Bulgaria', 'Europe: 	Czech Republic', 'Europe: 	Germany', 'Europe: 	Denmark', 'Europe: 	Estonia', 'Europe: 	Spain', 'Europe: 	Finland', 'Europe: 	France', 'Europe: 	Greece', 'Europe: 	Croatia', 'Europe: 	Hungary', 'Europe: 	Ireland', 'Europe: 	Italy', 'Europe: 	Lithuania', 'Europe: 	Luxembourg', 'Europe: 	Latvia', 'Europe: 	Malta', 'Europe: 	Netherlands', 'Europe: 	Poland', 'Europe: 	Portugal', 'Europe: 	Romania', 'Europe: 	Sweden', 'Europe: 	Slovenia', 'Europe: 	Slovak Republic', 'Europe: 	United Kingdom', 'Europe: 	Switzerland', 'Europe: 	Norway', 'Europe: 	RoW Europe', 'India: 	India', 'Latin America: 	Brazil', 'Latin America: 	RoW America', 'Middle East: 	Cyprus', 'Middle East: 	Turkey', 'Middle East: 	RoW Middle East', 'North America: 	United States', 'North America: 	Canada', 'North America: 	Mexico', 'Other Asia: 	Japan', 'Other Asia: 	South Korea', 'Other Asia: 	Indonesia', 'Other Asia: 	RoW Asia and Pacific', 'Russia: 	Russian Federation'};
            app.Target_Region.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49'};
            app.Target_Region.Multiselect = 'on';
            app.Target_Region.Value = {};
            app.YearsLabel = uilabel(app.UIFigure);
            app.YearsLabel.Text = 'Years';
            app.Years = uilistbox(app.UIFigure);
            app.Years.Items = {'1995', '1996', '1997', '1998', '1999', '2000', '2001', '2002', '2003', '2004', '2005', '2006', '2007', '2008', '2009', '2010', '2011'};
            app.Years.Multiselect = 'on';
            app.Years.Value = {'1995'};
            app.HowdoyouwanttodisplaytheresultsLabel = uilabel(app.UIFigure);
            app.HowdoyouwanttodisplaytheresultsLabel.Text = 'How do you want to display the results? ';
            app.Output = uilistbox(app.UIFigure);
            app.Output.Items = {'In the unit of the resp. indicator', 'As shares in total global impacts'};
            app.Output.ItemsData = {'1', '2'};
            app.Output.Multiselect = 'on';
            app.Output.Value = {'1'};
            app.InsertthenameofyourfolderwheretheresultswillbestoredLabel = uilabel(app.UIFigure);
            app.InsertthenameofyourfolderwheretheresultswillbestoredLabel.Text = '2) Insert the name of your folder where the results will be stored:';
            app.FolderName = uieditfield(app.UIFigure, 'text');
            app.StartCalculation = uibutton(app.UIFigure, 'push');
            app.StartCalculation.ButtonPushedFcn = createCallbackFcn(app, @StartCalculationButtonPushed, true);
            app.StartCalculation.Text = '3. Start Calculation (Press Button)';
            app.ProgressLabel = uilabel(app.UIFigure);
            app.ProgressLabel.Text = 'Progress:';
            app.Progress = uieditfield(app.UIFigure, 'text');
            app.Progress.Editable = 'off';
            app.ForquestionscontactliviacabernardistpethzchLabel = uilabel(app.UIFigure);
            app.ForquestionscontactliviacabernardistpethzchLabel.Text = 'For questions contact: livia.cabernard@istp.ethz.ch';
            app.Panel = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '*selected in the Global Resource Outlook (2019) & Cabernard et al (2019)';
            app.EnvironmentalEandSocioEconomicIndicatorsSLabel = uilabel(app.Panel);
            app.EnvironmentalEandSocioEconomicIndicatorsSLabel.Text = 'Environmental (E) and Socio-Economic Indicators (S)';
            app.Indicators = uilistbox(app.Panel);
            app.Indicators.Items = {'E:	Material footprint (kt)*', 'E:	Unused material footprint (kt)', 'E:	Climate change impacts: total (kg CO2-eq)*', 'E:	CO2-related climate change impacts (kg CO2-eq)', 'E:	CH4-related climate change impacts (kg CO2-eq)', 'E:	N2O-related climate change impacts (kg CO2-eq)', 'E:	HFC-related climate change impacts (kg CO2-eq)', 'E:	PFC-related climate change impacts (kg CO2-eq)', 'E:	Particulate-matter rel. health impacts: total (DALYs)*', 'E:	PM2.5-related health impacts (DALYs)', 'E:	SOx-related health impacts (DALYs)', 'E:	NOx-related health impacts (DALYs)', 'E:	NH3-related health impacts (DALYs)', 'E:	Water Stress (Mio. m3 H2O-eq)*', 'E:	Blue water consumption (Mio. m3 H2O)', 'E:	Green water consumption (Mio. m3 H2O)', 'E:	Land-use related biodiversity loss (global PDF)*', 'E:	Land-use area – total (km2)', 'E:	Cropland (km2)', 'E:	Pemanent pastures (km2)', 'E:	Forest area: forestry (km2)', 'E:	Forest area: marginal use (km2)', 'E:	Infrastructure land (km2)', 'E:	Other land use (km2)', 'E:	Energy Demand (TJ)', 'E:	CH4 air emissions (kg)', 'E:	N2O air emissions (kg)', 'E:	CO air emissions (kg)', 'E:	PM2.5 air emissions (kg)', 'E:	SOx air emissions (kg)', 'E:	NOx air emissions (kg)', 'E:	NH3 air emissions (kg)', 'E:	Benzo(a)pyrene air emissions (kg)', 'E:	Benzo(b)fluoranthene air emissions (kg)', 'E:	Benzo(k)fluoranthene air emissions (kg)', 'E:	Indeno air emissions (kg)', 'E:	PCB air emissions (kg)', 'E:	PCDD/F air emissions (kg)', 'E:	PAH air emissions (kg)', 'E:	HCB air emissions (kg)', 'E:	NMVOC air emissions (kg)', 'E:	PM10 air emissions (kg)', 'E:	TSP air emissions (kg)', 'E:	SF6 air emissions (kg)', 'E:	As air emissions (kg)', 'E:	Cd air emissions (kg)', 'E:	Cr air emissions (kg)', 'E:	Cu air emissions (kg)', 'E:	Hg air emissions (kg)', 'E:	Ni air emissions (kg)', 'E:	Pb air emissions (kg)', 'E:	Se air emissions (kg)', 'E:	Zn air emissions (kg)', 'E:	N water emissions (kg)', 'E:	P water emissions (kg)', 'E:	P soil emissions (kg)', 'S:	Value added: total (Mio. Euro)*', 'S:	Compensation of total workforce (Mio. Euro)*', 'S:	Compensation of low-skilled workforce (Mio. Euro)', 'S:	Compensation of medium-skilled workforce (Mio. Euro)', 'S:	Compensation of high-skilled workforce (Mio. Euro)', 'S:	Operating surplus (Mio. Euro)', 'S:	Taxes (Mio. Euro)', 'S:	Workforce: total (1000 people FTE)*', 'S:	Workforce: Low-skilled male (1000 people FTE)', 'S:	Workforce: Low-skilled female (1000 people FTE)', 'S:	Workforce: Medium-skilled male (1000 people FTE)', 'S:	Workforce: Medium-skilled female (1000 people FTE)', 'S:	Workforce: High-skilled male (1000 people FTE)', 'S:	Workforce: High-skilled female (1000 people FTE)', 'S:	Workforce: Vulnerable Workforce (1000 people FTE)'};
            app.Indicators.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '81'};
            app.Indicators.Multiselect = 'on';
            app.Indicators.Value = {};
            app.Panel_2 = uipanel(app.UIFigure);
            app.WhichresultsdoyouwanttodisplayLabel = uilabel(app.Panel_2);
            app.WhichresultsdoyouwanttodisplayLabel.Text = 'Which results do you want to display?';
            app.Results = uilistbox(app.Panel_2);
            app.Results.Items = {'Single Perspectives (one table for each year and indicator): 	Production (Sectors & Regions)', 'Single Perspectives (one table for each year and indicator): 	Target (Sectors & Regions)', 'Single Perspectives (one table for each year and indicator): 	Final Supply (Sectors & Regions)', 'Single Perspectives (one table for each year and indicator): 	Final Demand (Categories & Regions)', 'Linkages (one table for each year and indicator): 	All linkages between Production and Target**', 'Linkages (one table for each year and indicator): 	All linkages between Target and Final Supply**', 'Linkages (one table for each year and indicator): 	All linkages between Target and Final Demand**', 'Linkages (one table for each year and indicator): 	All linkages between Production and Final Demand**', 'Linkages (one table for each year and indicator): 	Sectoral linkages between Production and Target ', 'Linkages (one table for each year and indicator): 	Sectoral linkages between Target and Final Supply ', 'Linkages (one table for each year and indicator): 	Regional linkages between Production and Target ', 'Linkages (one table for each year and indicator): 	Regional linkages between Target and Final Demand ', 'Linkages (one table for each year and indicator): 	Regional linkages between Production and Final Demand ', 'Linkages (one table for each year and indicator): 	Linkages between Target-Sectors and Final-Demand-Regions', 'Linkages (one table for each year and indicator): 	Linkages between Production-Sectors and Final-Demand-Regions', 'Multiple indicators compiled (one table for each year): 	Production (Sectors & Regions)', 'Multiple indicators compiled (one table for each year): 	Target (Sectors & Regions)', 'Multiple indicators compiled (one table for each year): 	Final Supply (Sectors & Regions)', 'Multiple indicators compiled (one table for each year): 	Final Demand (Categories & Regions)', 'Multiple indicators compiled (one table for each year): 	Production-Sectors', 'Multiple indicators compiled (one table for each year): 	Target-Sectors', 'Multiple indicators compiled (one table for each year): 	Final-Supply-Sectors', 'Multiple indicators compiled (one table for each year): 	Production-Regions', 'Multiple indicators compiled (one table for each year): 	Target-Regions', 'Multiple indicators compiled (one table for each year): 	Final-Supply-Regions', 'Multiple indicators compiled (one table for each year): 	Final-Demand-Regions', 'Multiple indicators compiled (one table for each year): 	Final-Demand-Categories', 'Timelines for single indicators (one table for each indicator): 	Production (Sectors & Regions)', 'Timelines for single indicators (one table for each indicator): 	Target (Sectors & Regions)', 'Timelines for single indicators (one table for each indicator): 	Final Supply (Sectors & Regions)', 'Timelines for single indicators (one table for each indicator): 	Final Demand (Categories & Regions)', 'Timelines for single indicators (one table for each indicator): 	Production-Sectors', 'Timelines for single indicators (one table for each indicator): 	Target-Sectors', 'Timelines for single indicators (one table for each indicator): 	Final-Supply-Sectors', 'Timelines for single indicators (one table for each indicator): 	Production-Regions', 'Timelines for single indicators (one table for each indicator): 	Target-Regions', 'Timelines for single indicators (one table for each indicator): 	Final-Supply-Regions', 'Timelines for single indicators (one table for each indicator): 	Final-Demand-Regions', 'Timelines for single indicators (one table for each indicator): 	Final-Demand-Categories', 'Timelines for multiple Indictators (total impacts for each indicator)'};
            app.Results.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40'};
            app.Results.Multiselect = 'on';
            app.Results.Value = {'1'};
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '**time-consuming (particularly when several indicators and years are selected)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Sustainability_Performance_Analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end